classdef BT_Detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModelInfoLabel             matlab.ui.control.Label
        BeyinTmrTanUygulamasLabel  matlab.ui.control.Label
        ModelDropDown              matlab.ui.control.DropDown
        Label_2                    matlab.ui.control.Label
        TahminLabel                matlab.ui.control.Label
        ClassifyButton             matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SelectedImage  % Kullanıcının seçtiği resmi burada saklayacağız
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
[file, path] = uigetfile({'*.jpg;*.jpeg;*.png','Image Files (*.jpg, *.jpeg, *.png)'});
if isequal(file, 0)
    return; % Dosya seçilmediyse çık
end

imgPath = fullfile(path, file);
img = imread(imgPath);

% Griye çevir (3 kanal varsa)
if size(img, 3) == 3
    img = rgb2gray(img);
end

% Axes üzerinde göster
imshow(img, 'Parent', app.UIAxes);

% Seçilen resmi app içinde kaydet (sonradan sınıflandırmak için)
app.SelectedImage = img;


        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
if isempty(app.SelectedImage)
        uialert(app.UIFigure, 'Lütfen önce bir resim seçin.', 'Uyarı');
        return;
    end

    selectedModel = app.ModelDropDown.Value;

switch selectedModel
    case 'CNN - Epoch20'
        load('brain_tumor_cnn_epoch20.mat', 'cnnModel');
        infoText = "Validasyon: %94.59 | Test: %89.47";

    case 'GoogleNet'
    load('trainedGoogleNetModel.mat');  % sadece dosyayı yükle
    cnnModel = googlenetModel;          % dosyadaki doğru isme ata
    infoText = "Validasyon: %94.59 | Test: %94.73";

    case 'MobileNetV2'
        load('trainedMobileNetModel.mat', 'mobilenetModel');
        cnnModel = mobilenetModel;
        infoText = "Validasyon: %91.89 | Test: %89.47";

    case 'ResNet50'
        load('trainedResNetModel.mat', 'resnetModel');
        cnnModel = resnetModel;
        infoText = "Validasyon: %92.11 | Test: %86.49";

    otherwise
        uialert(app.UIFigure, 'Geçerli bir model seçilmedi.', 'Uyarı');
        return;
end

% 🎯 BURASI ÖNEMLİ: Label'a yaz
app.ModelInfoLabel.Text = infoText;

    inputSize = cnnModel.Layers(1).InputSize;
    img = imresize(app.SelectedImage, inputSize(1:2));

    if size(img, 3) == 1
        img = cat(3, img, img, img);
    end

    YPred = classify(cnnModel, img);
app.TahminLabel.Text = ['Tahmin: ', char(YPred)];

% 🌿 RENK AYARI
if strcmpi(char(YPred), 'no')
    app.TahminLabel.FontColor = [0 0.6 0];  % yeşil
elseif strcmpi(char(YPred), 'yes')
    app.TahminLabel.FontColor = [1 0 0];  % yeşil
else
    app.TahminLabel.FontColor = [0 0 0];    % bilinmeyen için siyah
end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            % Grafik etiketlerini kaldır
    xlabel(app.UIAxes, '');
    ylabel(app.UIAxes, '');
    title(app.UIAxes, '');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Resim Seç';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Sınıflandır';
            app.TahminLabel = uilabel(app.UIFigure);
            app.TahminLabel.Text = 'Tahmin:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Model Tipi';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'CNN - Epoch20', 'GoogleNet', 'MobileNetV2', 'ResNet50'};
            app.ModelDropDown.Value = 'CNN - Epoch20';
            app.BeyinTmrTanUygulamasLabel = uilabel(app.UIFigure);
            app.BeyinTmrTanUygulamasLabel.Text = 'Beyin Tümörü Tanı Uygulaması';
            app.ModelInfoLabel = uilabel(app.UIFigure);
            app.ModelInfoLabel.Text = 'Model Bilgisi:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BT_Detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end